classdef analysis_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RerouteButton            matlab.ui.control.Button
        ResultTextArea           matlab.ui.control.TextArea
        Label_2                  matlab.ui.control.Label
        ExitButton               matlab.ui.control.Button
        ExtractFeatureExplanationButton  matlab.ui.control.Button
        AnalyzeButton            matlab.ui.control.Button
        SelectFaultTypeDropDown  matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
           faultType = app.SelectFaultTypeDropDown.Value;

            % Generate simulated OTDR trace
            [distance, signal, faultLocation, isFaultDetected] = generate_traces(faultType);

            % Plot the OTDR trace
            cla(app.UIAxes);
            plot(app.UIAxes, distance, signal, 'k', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            xlabel(app.UIAxes, 'Distance (km)');
            ylabel(app.UIAxes, 'Signal (dB)');
            grid(app.UIAxes, 'on');

            % Mark fault point
            if isFaultDetected
                plot(app.UIAxes, distance(faultLocation), signal(faultLocation), 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                title(app.UIAxes, sprintf('OTDR Trace - %s Fault (%.2f km)', faultType, distance(faultLocation)));
            else
                title(app.UIAxes, sprintf('OTDR Trace - %s (No Fault Detected)', faultType));
            end
            hold(app.UIAxes, 'off');

            % Extract features
            meanSignal = mean(signal);
            stdSignal = std(signal);
            minSignal = min(signal);
            maxSignal = max(signal);

            % Display result
            %if isFaultDetected
             %app.ResultTextArea.Value = sprintf([ ...
                    %'Fault Type: %s\n' ...
                    %'Mean: %.2f | StdDev: %.2f\n' ...
                    %'Min: %.2f | Max: %.2f\n' ...
                    %'Fault Location: %.2f km\n' ...
                    %'Explanation: Possible %s fault detected.' ...
                    %], faultType, meanSignal, stdSignal, minSignal, maxSignal, distance(faultLocation), faultType);
                %app.RerouteButton.Enable = 'on';
            %else
                %app.ResultTextArea.Value = sprintf([ ...
                    %'Fault Type: Normal\n' ...
                    %'Mean: %.2f | StdDev: %.2f\n' ...
                    %'Min: %.2f | Max: %.2f\n' ...
                    %'Explanation: No fault detected.' ...
                    %], meanSignal, stdSignal, minSignal, maxSignal);
               % app.RerouteButton.Enable = 'off';
            %end
        end

        % Button pushed function: ExtractFeatureExplanationButton
        function ExtractFeatureExplanationButtonPushed(app, event)
           faultType = app.SelectFaultTypeDropDown.Value;

            % Get trace and extract features
            [distance, signal] = generate_traces(faultType);
            features = extract_features(distance, signal);
            explanation = fault_explanation(faultType);

            % Prepare display text
            txt = sprintf(['Fault Type: %s\n', ...
                'Mean: %.2f | StdDev: %.2f\n', ...
                'Min: %.2f | Max: %.2f\n', ...
                'Explanation:\n%s'], ...
                faultType, features.Mean, features.StdDev, ...
                features.Min, features.Max, ...
                explanation);

            % Display in text area
            app.ResultTextArea.Value = txt;

            % Show reroute button only if fault detected
            if ~strcmp(faultType, 'Normal')
                app.RerouteButton.Visible = 'on';
            else
                app.RerouteButton.Visible = 'off';
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: RerouteButton
        function RerouteButtonPushed(app, event)
        faultType = app.SelectFaultTypeDropDown.Value;

            % Define fault locations based on fault type
            switch faultType
                case 'Cut'
                    faultLocation = 'B-C';
                case 'Bend'
                    faultLocation = 'C-D';
                case 'Splice'
                    faultLocation = 'A-B';
                otherwise
                    faultLocation = '';
            end

            if isempty(faultLocation)
                uialert(app.UIFigure, 'No fault detected. Rerouting not required.', 'Info');
            else
                reroute_network(app.UIAxes, faultType, faultLocation);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Select Fault Type';
            app.SelectFaultTypeDropDown = uidropdown(app.UIFigure);
            app.SelectFaultTypeDropDown.Items = {'Normal', 'Cut', 'Bend', 'Splice'};
            app.SelectFaultTypeDropDown.Value = 'Normal';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ExtractFeatureExplanationButton = uibutton(app.UIFigure, 'push');
            app.ExtractFeatureExplanationButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFeatureExplanationButtonPushed, true);
            app.ExtractFeatureExplanationButton.Text = 'Extract Feature & Explanation';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ResultTextArea';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.RerouteButton = uibutton(app.UIFigure, 'push');
            app.RerouteButton.ButtonPushedFcn = createCallbackFcn(app, @RerouteButtonPushed, true);
            app.RerouteButton.Text = 'RerouteButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end